classdef EMG_record_DR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RecordPanel            matlab.ui.container.Panel
        GestureTextArea        matlab.ui.control.TextArea
        GestureTextAreaLabel   matlab.ui.control.Label
        startButton            matlab.ui.control.Button
        trialEditField         matlab.ui.control.NumericEditField
        trialEditFieldLabel    matlab.ui.control.Label
        sessionEditField       matlab.ui.control.EditField
        sessionEditFieldLabel  matlab.ui.control.Label
        ComportEditField       matlab.ui.control.NumericEditField
        ComportEditFieldLabel  matlab.ui.control.Label
        RecordTimeGauge        matlab.ui.control.LinearGauge
        RecordTimeGaugeLabel   matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        RecordButton           matlab.ui.control.Button
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialPort;
        Byte = 9;
            
        class = 8;
        record = false;

        a, b;
        a60, b60;

        fs = 1000;

        channels = 3;
        
        history_time = 1;
        record_time = 1;
           
        Running = false;
        cnt;    % parsing한 데이터 개수

        record_cnt = 1;
        
        x_preprocessed;
        data_struct_record;

        datadir='Data_gesture';

        gest_list;
        try_cnt;

        gest_cnt;

        plot_count;
        clear_count;

        sess;

    end
    
    methods (Access = private)
        
        function [data_all, left_data] = parsing(app, rawData)

            data_all = [];
            first_idx = [];
            last_idx = [];
            flag = 0;
            
            for q = 1:length(rawData)-2
                if rawData(q) == 170 && rawData(q+1) == 119 && rawData(q+2) == 170
                    flag = flag + 1;
                    if flag == 1
                        first_idx = q;
                    else
                        last_idx = q;
                    end
                end
            end

            for k = first_idx:last_idx - 3
                if rawData(k) == 170 && rawData(k+1) == 119 && rawData(k+2) == 170
                    Data = [];
                    for i = 1:app.channels
                        rdata_int = rawData(k+2*i+1)*256 + rawData(k+2*(i+1)); 
                        rdata_cal = ((rdata_int/ 4095) * 3.0  - 1.5)*1000.0; % mV
                        Data = [Data rdata_cal];
                    end
                    data_all = [data_all; Data];
                    app.cnt = app.cnt + 1;
                end
            end
           
           left_data = rawData(last_idx:end);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.b, app.a] = butter(2, [20 450] / (app.fs/2), 'bandpass');
            [app.b60, app.a60] = butter(2, [58 62] / (app.fs/ 2), 'stop');
            app.plot_count = app.fs / 10; % 100
            app.clear_count = 4*app.fs; % 2000

            % linspace for the x values of plot
            app.x_preprocessed = linspace(0,app.record_time,app.fs*app.record_time);
            
            app.gest_cnt = 1;

        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.Enable = 'off';
            % initialize the record data and chunk counter
            app.data_struct_record = zeros(app.fs * app.record_time, app.channels);
            app.record_cnt = 1;
            
            app.try_cnt = app.trialEditField.Value;
            app.sess = app.sessionEditField.Value;
            gestsessdir = ['gesture_list_session_', num2str(app.sess), '.csv'];
           
            
            app.gest_list = readtable(gestsessdir, 'FileEncoding', 'UTF-8');
            app.GestureTextArea.Value = sprintf('%s',app.gest_list.content{(app.try_cnt-1)*app.class + app.gest_cnt});

            % if the app is connected to ActiView
            if app.Running
                app.record = true;  % record start
            end

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.SerialPort)
                fclose(app.SerialPort);
                clear app.SerialPort;
            end

            app.Running = false;
           
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            try
                comPortNumber = app.ComportEditField.Value;
                comPortString = sprintf("COM%d", comPortNumber);
                app.SerialPort = serialport(comPortString, 115200);
                app.Running = true;

            catch
                msgbox('Serial port is not available please check port number');
                app.Running = false;
            end
          
            app.cnt = 1;

            left_data = [];
            data_struct = [];

            while app.Running
                
                rawData = read(app.SerialPort, app.Byte*3, "uint8");

                if ~isempty(left_data)
                    rawData = [left_data rawData];
                end

                             
                [data, left_data] = app.parsing(rawData);
                data_struct = [data_struct; data];

                if app.record
                    
                    app.record_cnt = app.record_cnt + 1;
                    if mod(app.record_cnt, 10) == 0
                        app.RecordTimeGauge.Value = app.record_cnt / app.fs;
                        drawnow limitrate;
                    end

                    if app.record_cnt > app.fs*(app.history_time + app.record_time)

                        app.record = false;
                        app.RecordTimeGauge.Value = 0;

                        app.data_struct_record = data_struct(end - app.fs*app.record_time + 1:end, :);

                        % plot the preprocessed data
                        drawnow;

                        plot(app.UIAxes, app.x_preprocessed, app.data_struct_record(:, 1));
                        plot(app.UIAxes2, app.x_preprocessed, app.data_struct_record(:, 2));
                        plot(app.UIAxes3, app.x_preprocessed, app.data_struct_record(:, 3));

                        % filtering the data
                        data_preprocessed = filtfilt(app.b60,app.a60, app.data_struct_record);
                        data_preprocessed = filtfilt(app.b,app.a, data_preprocessed);

                        % save the data
                        try
                            get_data =[];
                            get_data.save_data = data_preprocessed;
                            get_data.save_data_raw = app.data_struct_record;
                            
                            sessdir = fullfile(app.datadir, strcat('session', num2str(app.sess)));
                            trialdir = fullfile(sessdir, strcat('trial', num2str(app.try_cnt)));

                            if ~exist(sessdir, 'dir')
                                   mkdir(sessdir)
                            end

                            if ~exist(trialdir, 'dir')
                                   mkdir(trialdir)
                            end
                            save(fullfile(trialdir, strcat('gesture', num2str(app.gest_list.word((app.try_cnt-1)*app.class + app.gest_cnt)), '.mat')), "get_data");

                            combinedFigure = figure;
                            set(combinedFigure, 'Units', 'normalized', 'Position', [0, 0, 1, 1]); % 전체 화면 넓이와 높이를 설정

                            % Tiled layout 사용 (2x1 레이아웃)
                            t = tiledlayout(3, 1, 'TileSpacing', 'compact', 'Padding', 'compact'); % 4x1 레이아웃, 간격 최소화

                            nexttile;
                            copyobj(app.UIAxes.Children, gca);
                            title('CH1');
                            nexttile;
                            copyobj(app.UIAxes2.Children, gca);
                            title('CH2');
                            nexttile;
                            copyobj(app.UIAxes3.Children, gca);
                            title('CH3');

                            saveas(combinedFigure, fullfile(trialdir, strcat('gesture', num2str(app.gest_list.word((app.try_cnt-1)*app.class + app.gest_cnt)), '.jpg'))); 
                            close(combinedFigure);

                            app.gest_cnt = app.gest_cnt + 1;
                            app.RecordButton.Enable = 'on';
                            app.record = false;

                            if app.gest_cnt > app.class
                                app.GestureTextArea.Value = 'Thank you';
                                app.gest_cnt = 1;
                            else
                                app.GestureTextArea.Value = sprintf('%s',app.gest_list.content{(app.try_cnt-1)*app.class + app.gest_cnt});
                            end
                        catch
                            msgbox('Data Save Failed');
                        end
                        
                    end
                else

                    if app.cnt > app.plot_count

                        x_data = round(linspace(1, size(data_struct, 1)-1, size(data_struct, 1)));

                        plot(app.UIAxes, x_data, data_struct(:, 1));
                        plot(app.UIAxes2, x_data, data_struct(:, 2));
                        plot(app.UIAxes3, x_data, data_struct(:, 3));

                        hold(app.UIAxes, 'on');
                        hold(app.UIAxes2, 'on');
                        hold(app.UIAxes3, 'on');

                        hold(app.UIAxes, 'off');
                        hold(app.UIAxes2, 'off');
                        hold(app.UIAxes3, 'off');

                        if size(data_struct, 1) > app.clear_count

                            data_struct = data_struct((end-app.clear_count + app.cnt-1):end, :);

                        end
                        app.cnt = 1;
                    end
                end

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'CH3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.RecordButton = uibutton(app.RecordPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.RecordPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RecordTimeGaugeLabel = uilabel(app.RecordPanel);
            app.RecordTimeGaugeLabel.Text = 'Record Time';
            app.RecordTimeGauge = uigauge(app.RecordPanel, 'linear');
            app.RecordTimeGauge.Limits = [0 2];
            app.RecordTimeGauge.MajorTicks = [0 1 2];
            app.RecordTimeGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.ComportEditFieldLabel = uilabel(app.RecordPanel);
            app.ComportEditFieldLabel.Text = 'Comport';
            app.ComportEditField = uieditfield(app.RecordPanel, 'numeric');
            app.sessionEditFieldLabel = uilabel(app.RecordPanel);
            app.sessionEditFieldLabel.Text = 'session';
            app.sessionEditField = uieditfield(app.RecordPanel, 'text');
            app.sessionEditField.Value = '0';
            app.trialEditFieldLabel = uilabel(app.RecordPanel);
            app.trialEditFieldLabel.Text = 'trial';
            app.trialEditField = uieditfield(app.RecordPanel, 'numeric');
            app.startButton = uibutton(app.RecordPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.GestureTextAreaLabel = uilabel(app.RecordPanel);
            app.GestureTextAreaLabel.Text = 'Gesture';
            app.GestureTextArea = uitextarea(app.RecordPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_record_DR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end