classdef EMG_with_DR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        COMEditField       matlab.ui.control.NumericEditField
        COMEditFieldLabel  matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        DroneButton        matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        BackwardButton     matlab.ui.control.Button
        ForwardButton      matlab.ui.control.Button
        RightButton        matlab.ui.control.Button
        LeftButton         matlab.ui.control.Button
        DownButton         matlab.ui.control.Button
        UpButton           matlab.ui.control.Button
        LoadmodelButton    matlab.ui.control.Button
        allStopButton      matlab.ui.control.Button
        StartButton        matlab.ui.control.Button
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Running = false;
        
        channels = 3;   % 3
        Byte = 9;       % 9

        modeldir = 'model/trained_model_3ch_lstm.mat';     % 모델명하고 경로 수
        model_loaded;
        model;
        model_count;


        predictions = [];

        SerialPort;
        
        fs = 1000;
        window_size = 100;

        plot_count;
        clear_count;
        predict_count=1;
        check_window= 3; 
 
        gesture_start;
       

        cnt;
        %for filter
        b
        a
        
        b60
        a60
        
        zi1
        zi2
        
        gesture_list = {'forward','backward','up','down','left','right','stop','NaN'};
        gesture;
        class=8;

        input;
         
        fmin=20;
        fmax=450;
        idx_range;
        freq_bins;
        time_frames;
        epsilon = 1e-6;

        winLen;
        overlap;
        fftLen;
    end
    
    methods (Access = private)
        
        function [data_all, left_data] = parsing(app, rawData)
            data_all = [];
            first_idx = [];
            last_idx = [];
            flag = 0;
            
            for q = 1:length(rawData)-2
                if rawData(q) == 170 && rawData(q+1) == 119 && rawData(q+2) == 170
                    flag = flag + 1;
                    if flag == 1
                        first_idx = q;
                    else
                        last_idx = q;
                    end
                end
            end

            for k = first_idx:last_idx - 3
                if rawData(k) == 170 && rawData(k+1) == 119 && rawData(k+2) == 170
                    Data = [];
                    for i = 1:app.channels
                        rdata_int = rawData(k+2*i+1)*256 + rawData(k+2*(i+1)); 
                        rdata_cal = ((rdata_int/ 4095) * 10.0 - 5.0)*1000.0; % mV
                        Data = [Data rdata_cal];
                    end
                    data_all = [data_all; Data];
                    app.cnt = app.cnt + 1;
                end
            end
           
           left_data = rawData(last_idx:end);
            
        end
        
        function reset_color(app)
            app.StopButton.BackgroundColor = [0.96,0.96,0.96];
            app.ForwardButton.BackgroundColor = [0.96,0.96,0.96];
            app.BackwardButton.BackgroundColor = [0.96,0.96,0.96];
            app.LeftButton.BackgroundColor = [0.96,0.96,0.96];
            app.RightButton.BackgroundColor = [0.96,0.96,0.96];
            app.UpButton.BackgroundColor = [0.96,0.96,0.96];
            app.DownButton.BackgroundColor = [0.96,0.96,0.96];            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.b60, app.a60] = butter(2, [55 65] / (app.fs/ 2), 'stop');
            [app.b, app.a] = butter(2, [20 450] / (app.fs/2), 'bandpass');
            
            app.zi1 = zeros(max(length(app.a60), length(app.b60)) - 1, 1);
            app.zi2 = zeros(max(length(app.a), length(app.b)) - 1, 1);

            app.plot_count = app.fs / 10; % 100
            app.clear_count = app.fs*5; % 5000
            app.model_loaded = false;
            app.gesture_start = false;
            app.model_count = 1;
            
            app.winLen = round(0.2 * app.fs);
            app.overlap = round(0.18 * app.fs);
            app.fftLen = 256;

            test_sig = zeros(app.fs,1);
            [~, f, t_, ps] = spectrogram(test_sig, app.winLen, app.overlap, app.fftLen, app.fs);
            app.idx_range = (f >app.fmin) & (f <app.fmax);
            f_range = f(app.idx_range);
            app.freq_bins = numel(f_range);
            app.time_frames = numel(t_);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                comPortNumber = app.COMEditField.Value;
                comPortString = sprintf("COM%d", comPortNumber);
                app.SerialPort = serialport(comPortString, 115200);
                app.Running = true;
            catch
                msgbox('Serial port is not available please check port number');
                app.Running = false;
            end


            app.cnt = 1;

            left_data = [];
            data_struct = [];

            while app.Running
                
                rawData = read(app.SerialPort, app.Byte*3, "uint8");

                if ~isempty(left_data)
                    rawData = [left_data rawData];
                end
                        
                [data, left_data] = app.parsing(rawData);
                data_struct = [data_struct; data];
                
                if app.model_loaded && app.gesture_start
                    app.model_count = app.model_count + 1;
                    if app.model_count > app.window_size % 특정 간격마다 처리
                        
                        data = data_struct(end-app.fs+1:end, :);
                        [data_filtered, app.zi1] = filter(app.b60,app.a60, data, app.zi1); % LPF 
                        [data_filtered, app.zi2] = filter(app.b,app.a, data_filtered, app.zi2); % LPF, 필터 추가한 거로도 확인
                        stft_tensor = zeros(app.freq_bins, app.time_frames, app.channels);
                        
                        for ch = 1:app.channels
                            sig_ch = data_filtered(:, ch);
                            [~, ~, ~, ps] = spectrogram(sig_ch, app.winLen, app.overlap, app.fftLen, app.fs);
                            ps_db = 10 * log10(abs(ps)); % dB-scaled spectrogram
                            stft_tensor(:,:,ch) = ps_db(app.idx_range, :);

                        end

                        mu = mean(stft_tensor, [1 2]); sigma = std(stft_tensor, 0, [1 2]);
            
                        sigma(sigma < app.epsilon) = 1;
                        stft_tensor = (stft_tensor - mu) ./ sigma;
        
                        dsX1 =  arrayDatastore(stft_tensor(:, :, 1), 'IterationDimension', 4);
                        dsX2 =  arrayDatastore(stft_tensor(:, :, 2), 'IterationDimension', 4);
                        dsX3 =  arrayDatastore(stft_tensor(:, :, 3), 'IterationDimension', 4);
                   
                        dsX = combine(dsX1, dsX2, dsX3);

                        try

                            prob = predict(app.model, dsX);       
                            
                            app.predictions = [app.predictions; prob];
                            app.predict_count = app.predict_count + 1;
                            if app.predict_count > app.check_window
                                sum_prob = sum(app.predictions, 1);
                                [prob_max, pred] = max(sum_prob./norm(sum_prob));
                           
                                app.gesture = app.gesture_list{pred};   
                                disp(app.gesture)
                                app.predict_count = 1;
                                app.predictions = [];
                                app.reset_color();
                                switch app.gesture
                                    case 'stop'
                                        app.StopButtonPushed();
                                    case 'forward'
                                        app.ForwardButtonPushed();
                                    case 'backward'
                                        app.BackwardButtonPushed();
                                    case 'left'
                                        app.LeftButtonPushed();
                                    case 'right'
                                        app.RightButtonPushed();
                                    case 'up'
                                        app.UpButtonPushed();
                                    case 'down'
                                        app.DownButtonPushed();
                                    case 'NaN'
                                       app.reset_color();
                                end
                            end
                       
                        catch ME
                            % 예외 발생 시 메시지 출력
                            app.TextArea.Value = ['Failed to predict: ', ME.message];
                            disp('Error during prediction:');
                            disp(ME.message);

                        end

                        app.model_count = 1;
                    end
                    

                end

                if app.cnt > app.plot_count
                    
                    x_data = round(linspace(1, size(data_struct, 1)-1, size(data_struct, 1)));

                    plot(app.UIAxes, x_data, data_struct(:, 1));
                    plot(app.UIAxes2, x_data, data_struct(:, 2));
                    plot(app.UIAxes3, x_data, data_struct(:, 3));

                    if size(data_struct, 1) > app.clear_count

                        data_struct = data_struct((end-app.clear_count + app.cnt-1):end, :);
    
                    end
                    app.cnt = 1;
                end 
            end
        end

        % Button pushed function: LoadmodelButton
        function LoadmodelButtonPushed(app, event)
            app.TextArea.Value = 'Now Loading';
 
            try

                S = load(app.modeldir);
                app.model = S.net_final;

                app.input = S.inputSize;
          
                app.TextArea.Value = 'Model Loaded';
                app.model_loaded = true;
                

            catch ME
                
                app.TextArea.Value = ['Failed to load model: ', ME.message];
                disp(['Error Message: ', ME.message]);
   
            end
        end

        % Button pushed function: allStopButton
        function allStopButtonPushed(app, event)

             if ~isempty(app.SerialPort)
                fclose(app.SerialPort);
                clear app.SerialPort;
            end

            if app.model_loaded
               app.model_loaded = false;
            end

            app.Running = false;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Button pushed function: DroneButton
        function DroneButtonPushed(app, event)
            app.gesture_start = ~app.gesture_start;
           if app.gesture_start
               app.TextArea.Value = 'start';
           else
               app.TextArea.Value = '';
           end

        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.UpButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.DownButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.LeftButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.RightButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.ForwardButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            app.BackwardButton.BackgroundColor = [1.00 1.00 0.00];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.BackgroundColor = [1.00 1.00 0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'CH3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.allStopButton = uibutton(app.UIFigure, 'push');
            app.allStopButton.ButtonPushedFcn = createCallbackFcn(app, @allStopButtonPushed, true);
            app.allStopButton.Text = 'Stop';
            app.LoadmodelButton = uibutton(app.UIFigure, 'push');
            app.LoadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButtonPushed, true);
            app.LoadmodelButton.Text = 'Load model';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DroneButton = uibutton(app.UIFigure, 'push');
            app.DroneButton.ButtonPushedFcn = createCallbackFcn(app, @DroneButtonPushed, true);
            app.DroneButton.Text = 'Drone';
            app.TextArea = uitextarea(app.UIFigure);
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_with_DR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end